classdef FinalProject_DanielMasud_322250069 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HelpButton                  matlab.ui.control.Button
        FileNameLabel               matlab.ui.control.Label
        SpeedKnob                   matlab.ui.control.DiscreteKnob
        SpeedKnobLabel              matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        VolumeSlider                matlab.ui.control.Slider
        VolumeLabel                 matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        SpectrogramAxes             matlab.ui.control.UIAxes
        WaveformAxes                matlab.ui.control.UIAxes
    end

   properties (Access = private)
    AudioData
    Fs
    CurrentFile
    Player 
    IsPlaying = false              
    CurrentSampleIndex = 1  
    BufferSize = 16384
    PlayTimer
    ResampledAudio
    speedFactor = 1
   end
    

    methods (Access = private)
        
        function playAudioBlock(app)
     
     if ~app.IsPlaying
        stop(app.PlayTimer);
        delete(app.PlayTimer); 
        return;
     end
    
    i1 = app.CurrentSampleIndex;
    i2 = min(i1 + app.BufferSize - 1, length(app.ResampledAudio));

    block = zeros(app.BufferSize, size(app.ResampledAudio, 2));
    len = i2 - i1 + 1;
    block(1:len, :) = app.ResampledAudio(i1:i2, :);

    block = block * (app.VolumeSlider.Value / 100);
    app.Player(block);
    

    tCurrent = (i1 - 1) / app.Fs;
    
    delete(findall(app.WaveformAxes, 'Tag', 'playLine'));
    hold(app.WaveformAxes, 'on');
    xline(app.WaveformAxes, tCurrent, 'r', 'LineWidth', 1.5, 'Tag', 'playLine');
    hold(app.WaveformAxes, 'off');

    delete(findall(app.SpectrogramAxes, 'Tag', 'specLine'));
    hold(app.SpectrogramAxes, 'on');
    xline(app.SpectrogramAxes, tCurrent, 'r', 'LineWidth', 1.5, 'Tag', 'specLine');
    hold(app.SpectrogramAxes, 'off');

    app.CurrentSampleIndex = i2 + 1;

    if app.CurrentSampleIndex > length(app.ResampledAudio)
        app.IsPlaying = false;
        stop(app.PlayTimer);
        delete(app.PlayTimer);
    end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(2);
            uialert(app.UIFigure, ...
    sprintf(['Welcome to the Audio Playback and Analysis App!\n' ...
             'This app lets you load and play WAV files, control volume and speed, ' ...
             'and view advanced waveform and spectrogram plots.']), ...
    'Welcome','Icon','info');

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    [file, path] = uigetfile('*.wav');
    if isequal(file,0)
        return;
    end

    fullFile = fullfile(path, file);
    [y, Fs] = audioread(fullFile);
    
    app.AudioData = y;
    app.Fs = Fs;
    app.CurrentFile = fullFile;

 % Waveform
   t = (0:length(y)-1) / Fs;
   maxDisplayTime = 50;
   idx = t <= maxDisplayTime;
   t_disp = t(idx);
   y_disp = y(idx, 1);

    xlabel(app.WaveformAxes, 'Time (s)');
    ylabel(app.WaveformAxes, 'Amplitude');
    plot(app.WaveformAxes, t_disp, y_disp, 'k'); 
    title(app.WaveformAxes, 'Waveform');

    % Spectrogram
    y_spec = y(idx, 1);
    windowLength = 1024;
    overlap = 900;
    nfft = 1024;
    [s, f, t_spec, p] = spectrogram(y_spec, windowLength, overlap, nfft, Fs, 'yaxis');
    imagesc(app.SpectrogramAxes, t_spec, f, 10*log10(abs(p)));
    axis(app.SpectrogramAxes, 'xy');
    xlabel(app.SpectrogramAxes, 'Time (s)');
    ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
    title(app.SpectrogramAxes, 'Spectrogram');
    colormap(app.SpectrogramAxes, jet);
    colorbar(app.SpectrogramAxes);
    app.SamplingFrequencyEditField.Value = double(int64(Fs));
    
    [~, fileName, ext] = fileparts(file);
    app.FileNameLabel.Text = [fileName, ext];

    pause(1);
    uialert(app.UIFigure, ...
    sprintf('The file "%s" has been loaded successfully!', app.FileNameLabel.Text), ...
    'File Loaded','Icon','success');

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

    if isempty(app.AudioData)
        uialert(app.UIFigure, 'No audio file loaded.', 'Error');
        return;
    end

    if app.IsPlaying
        return;  
    end

    app.IsPlaying = true;

    
    speedStr = app.SpeedKnob.Value;
    app.speedFactor = str2double(extractAfter(speedStr, 'x'));
    
    
    app.ResampledAudio = app.AudioData;

    if app.CurrentSampleIndex > length(app.ResampledAudio) || app.CurrentSampleIndex <= 1
    app.CurrentSampleIndex = 1;
    end

    
    app.Player = audioDeviceWriter('SampleRate', app.Fs * app.speedFactor);
   
   
    app.PlayTimer = timer(...
        'ExecutionMode', 'fixedRate', ...
        'Period', app.BufferSize / (app.Fs * app.speedFactor), ...
        'TimerFcn', @(~,~) app.playAudioBlock());

    start(app.PlayTimer);
   
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            value = app.SpeedKnob.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          app.IsPlaying = false;
          app.Player(zeros(app.BufferSize, size(app.AudioData, 2)));
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)

    helpText = sprintf(['Welcome to the Audio Playback and Analysis App!\n\n' ...
        'Here''s how to use the app:\n' ...
        '1️⃣ Click "Load from .wav file..." to select a WAV file.\n' ...
        '2️⃣ Use the "Speed" knob to control playback speed.\n' ...
        '3️⃣ Adjust volume using the "Volume" slider.\n' ...
        '4️⃣ Click "Play" to start playing the audio.\n' ...
        '5️⃣ Click "Stop" to pause playback.\n' ...
        '6️⃣ View the waveform and spectrogram during playback.\n\n' ...
        'Enjoy!']);
    
    uialert(app.UIFigure, helpText, 'App Instructions', 'Icon', 'info');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WaveformAxes = uiaxes(app.UIFigure);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.SpectrogramAxes = uiaxes(app.UIFigure);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load from .wav file...';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedKnobLabel = uilabel(app.UIFigure);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'discrete');
            app.SpeedKnob.Items = {'x0.25', 'x0.5', 'x0.75', 'x1', 'x1.25', 'x1.5', 'x1.75', 'x2'};
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.Value = 'x1';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'No file loaded';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject_DanielMasud_322250069

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end